classdef ZeroCF_DERopt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DEROptimizerTab                matlab.ui.container.Tab
        CO2ReductionSlider             matlab.ui.control.Slider
        CO2ReductionSliderLabel        matlab.ui.control.Label
        NumberofoptimizationloopsEditField  matlab.ui.control.NumericEditField
        NumberofoptimizationloopsEditFieldLabel  matlab.ui.control.Label
        StartOptimizationButton        matlab.ui.control.Button
        SelectFacilityDropDown         matlab.ui.control.DropDown
        SelectFacilityDropDownLabel    matlab.ui.control.Label
        StepValueEditField             matlab.ui.control.NumericEditField
        StepValueEditFieldLabel        matlab.ui.control.Label
        FinishValueEditField           matlab.ui.control.NumericEditField
        FinishValueEditFieldLabel      matlab.ui.control.Label
        StartValueEditField            matlab.ui.control.NumericEditField
        StartValueEditFieldLabel       matlab.ui.control.Label
        IntialCO2ReductionPathLabel    matlab.ui.control.Label
        PlotElectricSources            matlab.ui.control.UIAxes
        PlotElectricLoads              matlab.ui.control.UIAxes
        OptimizationAnalysisTab        matlab.ui.container.Tab
        PlotLCOE                       matlab.ui.control.UIAxes
        PlotCostOfCO2                  matlab.ui.control.UIAxes
        PlotCapitalCost                matlab.ui.control.UIAxes
        ConfigurationTab               matlab.ui.container.Tab
        HydrogentechnologiesPanel      matlab.ui.container.Panel
        SwRenewableElectrolizerToggle  matlab.ui.control.Switch
        RenewableH2ElectrolizerLabel   matlab.ui.control.Label
        SwElectrolizerToggle           matlab.ui.control.Switch
        H2ElectrolizerLabel            matlab.ui.control.Label
        FilesLocationDropDown          matlab.ui.control.DropDown
        FilesLocationDropDownLabel     matlab.ui.control.Label
        DecemberCheckBox               matlab.ui.control.CheckBox
        NovemberCheckBox               matlab.ui.control.CheckBox
        OctoberCheckBox                matlab.ui.control.CheckBox
        SeptemberCheckBox              matlab.ui.control.CheckBox
        AugustCheckBox                 matlab.ui.control.CheckBox
        JulyCheckBox                   matlab.ui.control.CheckBox
        JuneCheckBox                   matlab.ui.control.CheckBox
        MayCheckBox                    matlab.ui.control.CheckBox
        AprilCheckBox                  matlab.ui.control.CheckBox
        MarchCheckBox                  matlab.ui.control.CheckBox
        FebruaryCheckBox               matlab.ui.control.CheckBox
        JanuaryCheckBox                matlab.ui.control.CheckBox
        Selectwhichmonthsfrom2018toincludeinthecalculationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)

        cfgManager
        optimizationRecordings

        plotData_text
        plotData_dt1
        plotData_dt2
        plotData_time
        plotData_stpts

    end
    
    properties (Access = private)
        Property3 % Description
    end

    methods (Access = private)
        
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function UpdateAllVariables(app)

            monthSel = [];

            if app.JanuaryCheckBox.Value == true
                monthSel = [monthSel 1];
            end
            if app.FebruaryCheckBox.Value == true
                monthSel = [monthSel 2];
            end
            if app.MarchCheckBox.Value == true
                monthSel = [monthSel 3];
            end
            if app.AprilCheckBox.Value == true
                monthSel = [monthSel 4];
            end            
            if app.MayCheckBox.Value == true
                monthSel = [monthSel 5];
            end
            if app.JuneCheckBox.Value == true
                monthSel = [monthSel 6];
            end
            if app.JulyCheckBox.Value == true
                monthSel = [monthSel 7];
            end
            if app.AugustCheckBox.Value == true
                monthSel = [monthSel 8];
            end
            if app.SeptemberCheckBox.Value == true
                monthSel = [monthSel 9];
            end
            if app.OctoberCheckBox.Value == true
                monthSel = [monthSel 10];
            end
            if app.NovemberCheckBox.Value == true
                monthSel = [monthSel 11];
            end
            if app.DecemberCheckBox.Value == true
                monthSel = [monthSel 12];
            end

            app.cfgManager.month_idx = monthSel;

            app.cfgManager.SetRunningEnvironment(str2double(app.FilesLocationDropDown.Value));

            if strcmpi(app.SwElectrolizerToggle.Value,'On') == 1
                app.cfgManager.el_on = 1;
            else
                app.cfgManager.el_on = 0;
            end

            if strcmpi(app.SwRenewableElectrolizerToggle.Value,'On') == 1
                app.cfgManager.rel_on = 1;
            else
                app.cfgManager.rel_on = 0;
            end

        end

        %------------------------------------------------------------------
        %------------------------------------------------------------------

        function UpdatePlots(app, ii)

            if ii > 0 && ii <= length(app.plotData_text)

                %--------------------------------------------------
                % Plot 'Electric Sources (MW)'
                %--------------------------------------------------
    
                app.PlotElectricSources.Title.String = app.plotData_text(ii);
                area(app.PlotElectricSources, app.plotData_time, app.plotData_dt1(:,:,ii).*4./1000)
                set(app.PlotElectricSources,'XTick', round(app.plotData_time(1),0)+.5:round(app.plotData_time(end),0)+.5,'FontSize',14)
                app.PlotElectricSources.Box = "on";
                app.PlotElectricSources.XGrid = "on";
                datetick(app.PlotElectricSources,'x','ddd','keepticks');
                app.PlotElectricSources.XLim = [app.plotData_time(app.plotData_stpts(3)) app.plotData_time(app.plotData_stpts(3)+96*7)];
                ylabel(app.PlotElectricSources,'Electric Sources (MW)','FontSize',14)
                legend(app.PlotElectricSources,'Gas Turbine','Utility Import','Solar','Battery Discharge','Location','Best')
                app.PlotElectricSources.Visible = true;
        
                %--------------------------------------------------
                % Plot 'Electric Loads (MW)'
                %--------------------------------------------------
    
                app.PlotElectricLoads.Title.String = app.plotData_text(ii);
                area(app.PlotElectricLoads, app.plotData_time, app.plotData_dt2(:,:,ii).*4./1000)
                set(app.PlotElectricLoads,'XTick', round(app.plotData_time(1),0)+.5:round(app.plotData_time(end),0)+.5,'FontSize',14)
                app.PlotElectricLoads.Box = "on";
                app.PlotElectricLoads.XGrid = "on";
                datetick(app.PlotElectricLoads,'x','ddd','keepticks');
                app.PlotElectricLoads.XLim = [app.plotData_time(app.plotData_stpts(3)) app.plotData_time(app.plotData_stpts(3)+96*7)];
                ylabel(app.PlotElectricLoads,'Electric Loads (MW)','FontSize',14)
                legend(app.PlotElectricLoads,'Campus','Battery Charging','H_2 Production','Export','Location','Best')
                app.PlotElectricLoads.Visible = true;
    
            end

        end

        %------------------------------------------------------------------
        %------------------------------------------------------------------

        function ValidateInitialPathCO2Reduction(app)

            app.NumberofoptimizationloopsEditField.Value = app.cfgManager.CalculateInitialPathCO2Reduction(app.StartValueEditField.Value,...
                app.FinishValueEditField.Value, app.StepValueEditField.Value);

            % Adjust slider
            app.CO2ReductionSlider.Limits = [app.StartValueEditField.Value app.FinishValueEditField.Value];
            app.CO2ReductionSlider.MajorTicks = app.cfgManager.co2_red * 100;

        end

        %------------------------------------------------------------------
        %------------------------------------------------------------------

        function Optimize(app)

            app.optimizationRecordings = [];
            app.plotData_text = [];
            app.plotData_dt1 = [];
            app.plotData_dt2 = [];
            app.plotData_time = [];
            app.plotData_stpts = [];

            prepSteps = 30;
            
            progBar = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Starting Optimization');            
            
            bldgData = CBuildingDataManager("UCI Labs");

            progBar.Value = 0;
            progBar.Message = "Loading Building Data ";
            
            if bldgData.LoadData(append(app.cfgManager.demo_data_path, '\Campus_Loads_2014_2019.mat'), app.cfgManager.chiller_plant_opt)

                progBar.Value = (1/prepSteps);
                progBar.Message = "Streamlining Building Data";
            
                bldgData.FormatData(app.cfgManager.month_idx, app.cfgManager.year_idx, app.cfgManager.demo_data_path, app.cfgManager.util_solar_on, app.cfgManager.util_wind_on, app.cfgManager.hrs_on);
           
                progBar.Value = (2/prepSteps);
                progBar.Message = "Recalibrating Emissions Parameters";
    
                if isempty(app.cfgManager.co2_base)
                    app.cfgManager.co2_base = bldgData.EstimateBaseCO2Emissions();
                end
                
                app.cfgManager.SetUpFirstCO2Limit();
                                
                progBar.Value = (3/prepSteps);
                progBar.Message = "Loading Utility Data and Generating Energy Charge Vectors";

                utilInfo = CUtilityInfo(app.cfgManager.uci_rate, app.cfgManager.export_on, app.cfgManager.gen_export_on, bldgData.lmp_uci, bldgData.GetElecLen());


                %--------------------------------------------------------------------------
                % Technology Parameters/Costs
                %--------------------------------------------------------------------------

                progBar.Value = (4/prepSteps);
                progBar.Message = "Calculating OnSite Technology Parameters & Costs";

                techSelOnSite = CTechnologySelection();
                techSelOnSite.CalculateAllParams(app.cfgManager.pv_on, app.cfgManager.ees_on, app.cfgManager.el_on, app.cfgManager.h2es_on, app.cfgManager.rel_on, app.cfgManager.hrs_on, app.cfgManager.h2_inject_on);
                
                
                %--------------------------------------------------------------------------
                % Technology Parameters/Costs for offsite resources
                %--------------------------------------------------------------------------

                progBar.Value = (5/prepSteps);
                progBar.Message = "Calculating OffSite Technology Parameters & Costs";

                techSelOffSite = CTechnologySelectionOffSite();
                techSelOffSite.CalculateAllParams(app.cfgManager.util_solar_on, app.cfgManager.util_wind_on, app.cfgManager.util_ees_on, app.cfgManager.util_el_on, app.cfgManager.util_h2_inject_on);
      

                %--------------------------------------------------------------------------
                % Legacy Technologies
                %--------------------------------------------------------------------------

                progBar.Value = (6/prepSteps);
                progBar.Message = "Calculating Legacy Technology Parameters & Costs";

                legacyTech = CLegacyTechnologies(app.cfgManager.lpv_on, app.cfgManager.ldg_on, app.cfgManager.lbot_on, app.cfgManager.lhr_on, app.cfgManager.ldb_on, app.cfgManager.lboil_on, app.cfgManager.lees_on, app.cfgManager.ltes_on, app.cfgManager.dg_legacy_cyc);
                
                app.cfgManager.dg_legacy_cyc = legacyTech.updatedDgLegacyCyc;      % TODO: review

                
                %--------------------------------------------------------------------------
                % Capital Cost Modifications
                %--------------------------------------------------------------------------
                
                progBar.Value = (7/prepSteps);
                progBar.Message = "Calculating Capital Costs Modifications (1/3)";

                capCostMods = CCapitalCostCalculator(app.cfgManager.interestRateOnLoans, app.cfgManager.lengthOfLoansYears, app.cfgManager.req_return_on);
                
                capCostMods.DebtPaymentsFullCostSystem(techSelOnSite.pv_v, techSelOnSite.ees_v, techSelOnSite.el_v, ...
                                                        techSelOnSite.rel_v, techSelOnSite.h2es_v, techSelOnSite.hrs_v, techSelOnSite.h2_inject_v,...
                                                        techSelOffSite.utilpv_v, techSelOffSite.util_wind_v, techSelOffSite.util_ees_v, ...
                                                        app.cfgManager.util_el_on, techSelOffSite.util_h2_inject_v, app.cfgManager.rees_on)
                
                capCostMods.ConvertIncentivesToReductions(techSelOnSite.sgip, techSelOnSite.ees_v)
                

                progBar.Value = (8/prepSteps);
                progBar.Message = "Calculating Capital Costs Modifications (2/3)";

                capCostMods.CalcCostScalars_SolarPV(techSelOnSite.pv_v, techSelOnSite.pv_fin, techSelOnSite.somah, bldgData.elec, app.cfgManager.maxpv, app.cfgManager.low_income)
                capCostMods.CalcCostScalars_EES(techSelOnSite.ees_v, techSelOnSite.ees_fin, techSelOnSite.rees_fin, techSelOnSite.pv_v, bldgData.elec, app.cfgManager.maxpv, app.cfgManager.rees_on)
                capCostMods.CalcCostScalars_Electrolizer(techSelOnSite.el_v, techSelOnSite.el_fin, bldgData.elec, app.cfgManager.h2_fuel_forced_fraction, app.cfgManager.low_income, bldgData.e_adjust)
                capCostMods.CalcCostScalars_RenewableElectrolizer(techSelOnSite.rel_v, techSelOnSite.rel_fin, bldgData.elec, app.cfgManager.h2_fuel_forced_fraction, app.cfgManager.low_income, bldgData.e_adjust)
                
                progBar.Value = (9/prepSteps);
                progBar.Message = "Calculating Capital Costs Modifications (3/3)";

                capCostMods.CalcCostScalars_UtilityScaleSolarPV(techSelOffSite.utilpv_v, techSelOffSite.utilpv_fin, bldgData.elec, techSelOnSite.somah, app.cfgManager.low_income)
                capCostMods.CalcCostScalars_UtilityScaleWind(techSelOffSite.util_wind_v, techSelOffSite.util_wind_fin, bldgData.elec, techSelOnSite.somah)
                capCostMods.CalcCostScalars_UtilityScaleBatteryStorage(techSelOffSite.util_ees_v, techSelOffSite.util_ees_fin, bldgData.elec, techSelOnSite.somah)
                capCostMods.CalcCostScalars_UtilityScaleElectrolyzer(techSelOffSite.util_el_v, techSelOffSite.util_el_fin, bldgData.elec)

  
                %--------------------------------------------------------------------------
                % Setting up variables and cost function
                %--------------------------------------------------------------------------
                modelVars = CModelVariables(bldgData.GetTimeLen(), bldgData.GetEndPointsLen());
                
                progBar.Value = (10/prepSteps);
                progBar.Message = "Generating OnSite Model Variables";

                modelVars.SetupUtilityElectricity(app.cfgManager.utility_exists, app.cfgManager.dc_exist, bldgData.day_multi, utilInfo)
                modelVars.SetupGeneralExport(app.cfgManager.gen_export_on, utilInfo)
                
                % Added NEM and wholesale export to the PV Section
                modelVars.SetupSolarPV(app.cfgManager.utility_exists, app.cfgManager.export_on, app.cfgManager.rees_on, app.cfgManager.island, bldgData.day_multi, techSelOnSite, utilInfo, capCostMods)
                modelVars.SetupElectricalEnergyStorage(app.cfgManager.sgip_on, bldgData.day_multi, techSelOnSite, capCostMods)
                modelVars.SetupRenewableElectrolyzer(app.cfgManager.util_pv_wheel_lts, app.cfgManager.strict_h2es, techSelOnSite.rel_v, techSelOnSite.el_v, techSelOnSite.h2es_v, capCostMods)
                modelVars.SetupH2ProductionAndStorage(app.cfgManager.util_pv_wheel_lts, app.cfgManager.strict_h2es, techSelOnSite.el_v, techSelOnSite.h2es_v, capCostMods)
                modelVars.SetupHRSEquipment(app.cfgManager.hrs_on, techSelOnSite.hrs_v, capCostMods)
                modelVars.SetupH2PipelineInjection(app.cfgManager.h2_inject_on, app.cfgManager.ng_inject, app.cfgManager.rng_storage_cost, capCostMods)
                modelVars.SetupLegacyPv(app.cfgManager.island, app.cfgManager.export_on, bldgData.day_multi, legacyTech.pv_legacy, techSelOnSite.pv_v, utilInfo)
                modelVars.SetupLegacyGenerator(app.cfgManager.h2_inject_on, app.cfgManager.util_h2_inject_on, app.cfgManager.ldg_op_state, legacyTech.dg_legacy, app.cfgManager.dg_legacy_cyc, techSelOnSite.el_v, techSelOnSite.rel_v, app.cfgManager.ng_cost, app.cfgManager.rng_cost)
                modelVars.SetupLegacyBottomingSystems(legacyTech.bot_legacy)
                modelVars.SetupLegacyHeatRecovery(legacyTech.dg_legacy, legacyTech.hr_legacy, app.cfgManager.ng_cost, app.cfgManager.rng_cost)
                modelVars.SetupLegacyBoiler(legacyTech.boil_legacy, app.cfgManager.ng_cost, app.cfgManager.rng_cost)
                modelVars.SetupLegacyGenericChiller(bldgData.cool, legacyTech.vc_legacy)
                modelVars.SetupLegacyEES(bldgData.day_multi, legacyTech.ees_legacy)
                modelVars.SetupLegacyColdTES(bldgData.cool, legacyTech.tes_legacy)
                modelVars.SetupLegacyChillers(app.cfgManager.onoff_model, bldgData.cool, legacyTech.vc_legacy)
                modelVars.SetupDumpVariables(bldgData.elec_dump)
                
                progBar.Value = (11/prepSteps);
                progBar.Message = "Generating OffSite Model Variables";

                % Setting up variables and cost function for offsite resources
                modelVars.SetupPowerPlantExports(app.cfgManager.util_solar_on, app.cfgManager.util_ees_on, app.cfgManager.util_pp_export, app.cfgManager.util_pp_import, app.cfgManager.util_pv_wheel, app.cfgManager.util_pv_wheel_lts)
                modelVars.SetupCommunityScaleSolar(techSelOffSite.utilpv_v)
                modelVars.SetupCommunityScaleWind(techSelOffSite.util_wind_v)
                modelVars.SetupCommunityScaleStorage(techSelOffSite.util_ees_v)
                modelVars.SetupRemoteElectrolyzer(techSelOffSite.util_el_v, bldgData.elec)
                modelVars.SetupUtilH2PipelineInjection(app.cfgManager.util_h2_inject_on)
                
                %--------------------------------------------------------------------------
                %                  MODEL CONSTRAINTS
                %--------------------------------------------------------------------------
                
                modelConstraints = CModelConstraints(bldgData.GetTimeLen(), bldgData.GetEndPointsLen());
                
                progBar.Value = (12/prepSteps);
                progBar.Message = "Model Constraints -> General Equality";

                modelConstraints.Calculate_GeneralEquality(app.cfgManager.onoff_model, modelVars, bldgData.heat, bldgData.cool, bldgData.elec, techSelOnSite.el_v, techSelOnSite.rel_v, app.cfgManager.hrs_on, app.cfgManager.util_solar_on, app.cfgManager.util_ees_on, app.cfgManager.util_pv_wheel_lts);
      
                progBar.Value = (13/prepSteps);
                progBar.Message = "Model Constraints -> General Inequality";

                modelConstraints.Calculate_GeneralInequality(modelVars, bldgData.e_adjust, app.cfgManager.utility_exists, app.cfgManager.dc_exist,...
                                                                bldgData.endpts, utilInfo.onpeak_index, utilInfo.midpeak_index, app.cfgManager.export_on, utilInfo.export_price,...
                                                                utilInfo.import_price, legacyTech.fac_prop, app.cfgManager.util_pv_wheel, app.cfgManager.util_ees_on, app.cfgManager.util_pp_import,...
                                                                app.cfgManager.h2_fuel_forced_fraction, techSelOnSite.el_v, app.cfgManager.h2_fuel_limit, app.cfgManager.ldg_on, app.cfgManager.co2_lim,...
                                                                app.cfgManager.biogas_limit, app.cfgManager.h2_charging_rec, app.cfgManager.gen_export_on, bldgData.co2_import, bldgData.co2_ng, bldgData.co2_rng);


                progBar.Value = (14/prepSteps);
                progBar.Message = "Model Constraints -> Heat Recovery Inequalities";

                modelConstraints.Calculate_HeatRecoveryInequality(modelVars, legacyTech.dg_legacy, legacyTech.hr_legacy, legacyTech.bot_legacy);

                progBar.Value = (15/prepSteps);
                progBar.Message = "Model Constraints -> Legacy DG Constraints";

                modelConstraints.Calculate_LegacyDG(modelVars, legacyTech.dg_legacy, bldgData.e_adjust, app.cfgManager.dg_legacy_cyc);

                progBar.Value = (16/prepSteps);
                progBar.Message = "Model Constraints -> Legacy ST Constraints";

                modelConstraints.Calculate_LegacyST(modelVars, legacyTech.bot_legacy);

                progBar.Value = (17/prepSteps);
                progBar.Message = "Model Constraints -> PV Constraints";

                modelConstraints.Calculate_SolarPV(modelVars, techSelOnSite.pv_v, bldgData.solar, legacyTech.pv_legacy, app.cfgManager.toolittle_pv, app.cfgManager.maxpv, app.cfgManager.curtail, bldgData.e_adjust);

                progBar.Value = (18/prepSteps);
                progBar.Message = "Model Constraints -> EES Constraints";

                modelConstraints.Calculate_EES(modelVars, techSelOnSite.ees_v, techSelOnSite.pv_v, app.cfgManager.rees_on);

                progBar.Value = (19/prepSteps);
                progBar.Message = "Model Constraints -> Legacy EES Constraints";

                modelConstraints.Calculate_LegacyEES(modelVars, legacyTech.ees_legacy);

                progBar.Value = (20/prepSteps);
                progBar.Message = "Model Constraints -> Legacy VC Constraints";

                modelConstraints.Calculate_LegacyVC(modelVars, app.cfgManager.onoff_model, bldgData.cool, legacyTech.vc_legacy);

                progBar.Value = (21/prepSteps);
                progBar.Message = "Model Constraints -> Legacy TES Constraints";

                modelConstraints.Calculate_LegacyTES(modelVars, bldgData.cool, legacyTech.tes_legacy);

                progBar.Value = (22/prepSteps);
                progBar.Message = "Model Constraints -> DER Incentives Constraints";

                modelConstraints.Calculate_DERIncentives(modelVars, techSelOnSite.ees_v, app.cfgManager.sgip_on);%% DER Incentives

                progBar.Value = (23/prepSteps);
                progBar.Message = "Model Constraints -> Electrolyzer and H2 Storage Constraints";

                modelConstraints.Calculate_H2production(modelVars, techSelOnSite.el_v, techSelOnSite.rel_v, techSelOnSite.h2es_v, bldgData.e_adjust);

                progBar.Value = (24/prepSteps);
                progBar.Message = "Model Constraints -> Utility Scale Solar Constraints";

                modelConstraints.Calculate_UtilitySolar(modelVars, techSelOffSite.utilpv_v, bldgData.e_adjust);

                progBar.Value = (25/prepSteps);
                progBar.Message = "Model Constraints -> Utility Scale Wind Constraints";

                modelConstraints.Calculate_UtilityWind(modelVars, techSelOffSite.util_wind_v, bldgData.e_adjust);

                progBar.Value = (26/prepSteps);
                progBar.Message = "Model Constraints -> Utility Scale Battery Storage Constraints";

                modelConstraints.Calculate_UtilityEESStorage(modelVars, techSelOffSite.util_ees_v);

                progBar.Value = (27/prepSteps);
                progBar.Message = "Model Constraints -> Utility Scale Electrolyzer Constraints";

                modelConstraints.Calculate_UtilityElectrolyzer(modelVars, techSelOffSite.util_el_v);

                progBar.Value = (28/prepSteps);
                progBar.Message = "Model Constraints -> H2 Pipeline Injection Constraints";

                modelConstraints.Calculate_H2PipelineInjection(modelVars, app.cfgManager.h2_inject_on);

                progBar.Value = (29/prepSteps);
                progBar.Message = "Model Constraints -> Creating Optimization Model";

                mSolver = CModelSolver;
                
                % Export Model
                mSolver.CreateModel(modelConstraints.Constraints, modelVars.Objective, app.cfgManager.co2_lim);
                
                if mSolver.SetupFirstSolve(app.cfgManager.co2_lim, app.cfgManager.co2_base, sum(bldgData.elec), bldgData.co2_import, bldgData.co2_ng, bldgData.co2_rng)
                
                end

                close(progBar);

                %--------------------------------------------------------------------------
                %                  OPTIMIZATION LOOP
                %--------------------------------------------------------------------------

                rec = [];

                app.plotData_time = bldgData.time;
                app.plotData_stpts = bldgData.stpts;

                optLoops = length(app.cfgManager.co2_red);
                
                for ii = 1:optLoops
                
                    loopUIText = ['Step ' num2str(ii) ' of ' num2str(optLoops) ' - Optiziming for '...
                                    num2str((app.cfgManager.co2_red(ii)*100),'%d') '% CO2 Reduction'];

                    progBar = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message',loopUIText); 

                    progBar.Value = ((ii-1)/optLoops);
                
                    mSolver.SolveModel(ii, app.cfgManager.co2_red(ii), modelVars);

                    close(progBar);
                
                    %% Starting Recorder Structure - Model Outputs
                    rec.solver.x(ii,:) = mSolver.lastSolveX;
                    rec.solver.fval(ii,1) = mSolver.lastSolveFval;
                    rec.solver.exitflag(ii,1) = mSolver.lastSolveExitFlag;
                    rec.solver.output(ii,1) = mSolver.lastSolveExitFlag;
                
                    %% Optimized Variables -  Utilities
                
                    %%% Utility Variables
                    rec.utility.import(:,ii) = mSolver.utilityImport;
                    rec.utility.nontou_dc(:,ii) = mSolver.utilityNontouDc;
                    rec.utility.onpeak_dc(:,ii) = mSolver.utilityOnpeakDc;
                    rec.utility.midpeak_dc(:,ii) = mSolver.utilityMidpeakDc;
                    rec.utility.gen_export(:,ii) = mSolver.utilityGenExport;
                
                    %% Optimized Variables - New Technologies
                    %%% Solar Variables
                    rec.solar.pv_adopt(:,ii) = mSolver.solarPhotoVoltaicAdopt;
                    rec.solar.pv_elec(:,ii) = mSolver.solarPhotoVoltaicElec;
                    rec.solar.pv_nem(:,ii) = mSolver.solarPhotoVoltaicNem;
                
                    %%% Electrical Energy Storage
                    rec.ees.ees_adopt(:,ii) = mSolver.electricalEnergyStorage_adopt;
                    rec.ees.ees_chrg(:,ii) = mSolver.electricalEnergyStorage_chrg;
                    rec.ees.ees_dchrg(:,ii) = mSolver.electricalEnergyStorage_dchrg;
                    rec.ees.ees_soc(:,ii) = mSolver.electricalEnergyStorage_soc;
                
                    %%% Renewable Electrical Energy Storage
                    rec.rees.rees_adopt(:,ii) = mSolver.renewableElectricalEnergyStorage_adopt;
                    rec.rees.rees_chrg(:,ii) = mSolver.renewableElectricalEnergyStorage_chrg;
                    rec.rees.rees_dchrg(:,ii) = mSolver.renewableElectricalEnergyStorage_dchrg;
                    rec.rees.rees_soc(:,ii) = mSolver.renewableElectricalEnergyStorage_soc;
                    rec.rees.rees_dchrg_nem(:,ii) = mSolver.renewableElectricalEnergyStorage_dchrg_nem;
                
                    %%% H2 Production - Electrolyzer
                    rec.el.el_adopt(:,ii) = mSolver.h2ProductionElectrolyzer_adopt;
                    rec.el.el_prod(:,ii) = mSolver.h2ProductionElectrolyzer_prod;
                
                    %%% H2 Production - Renewable Electrolyzer
                    rec.rel.rel_adopt(:,ii) = mSolver.h2ProductionRenewableElectrolyzer_adopt;
                    rec.rel.rel_prod(:,ii) = mSolver.h2ProductionRenewableElectrolyzer_prod;
                    rec.rel.rel_prod_wheel(:,ii) = mSolver.h2ProductionRenewableElectrolyzer_prod;
                
                    %%% H2 Production - Storage
                    rec.h2es.h2es_adopt(:,ii) = mSolver.h2ProductionEnergyStorage_adopt;
                    rec.h2es.h2es_chrg(:,ii) = mSolver.h2ProductionEnergyStorage_chrg;
                    rec.h2es.h2es_dchrg(:,ii) = mSolver.h2ProductionEnergyStorage_dchrg;
                    rec.h2es.h2es_soc(:,ii) = mSolver.h2ProductionEnergyStorage_soc;
                    rec.h2es.h2es_bin(:,ii) = mSolver.h2ProductionEnergyStorage_bin;
                
                    %% Optimized Variables -  Legacy technologies %%
                    %% DG - Topping Cycle
                    rec.ldg.ldg_elec(:,ii) = mSolver.ldg_elec;
                    rec.ldg.ldg_fuel(:,ii) = mSolver.ldg_fuel;
                    rec.ldg.ldg_rfuel(:,ii) = mSolver.ldg_rfuel;
                    rec.ldg.ldg_hfuel(:,ii) = mSolver.ldg_hfuel;
                    rec.ldg.ldg_sfuel(:,ii) = mSolver.ldg_sfuel;
                    rec.ldg.ldg_dfuel(:,ii) = mSolver.ldg_dfuel;
                    rec.ldg.ldg_elec_ramp(:,ii) = mSolver.ldg_elec_ramp;
                    % var_ldg.ldg_off(:,ii) = mSolver.ldg_off;
                    rec.ldg.ldg_opstate(:,ii) = mSolver.ldg_opstate;
                    %% Bottoming Cycle
                    rec.lbot.lbot_elec(:,ii) = mSolver.lbot_elec;
                    rec.lbot.lbot_on(:,ii) = mSolver.lbot_on;
                
                    %% Heat Recovery Systems
                    rec.ldg.hr_heat(:,ii) = mSolver.ldg_hr_heat;
                    rec.ldg.db_fire(:,ii) = mSolver.ldg_db_fire;
                    rec.ldg.db_rfire(:,ii) = mSolver.ldg_db_rfire;
                    rec.ldg.db_hfire(:,ii) = mSolver.ldg_db_hfire;
                
                    %% Boiler
                    rec.boil.boil_fuel(:,ii) = mSolver.boiler_fuel;
                    rec.boil.boil_rfuel(:,ii) = mSolver.boiler_rfuel;
                    rec.boil.boil_hfuel(:,ii) = mSolver.boiler_hfuel;
                
                    %% EES
                    if ~isempty(legacyTech.ees_legacy)
                        rec.lees.ees_chrg(:,ii) = mSolver.lees_chrg;
                        rec.lees.ees_dchrg(:,ii) = mSolver.lees_dchrg;
                        rec.lees.ees_soc(:,ii) = mSolver.lees_soc;
                    end
                
                    %% Carbon Emissions
                    % Carbon emissions from 1) utility electiricity, 2) natural gas, 3) renewable natural gas
                    rec.co2_emissions(1,ii) = mSolver.co2EmissionsUtilityElect;
                    rec.co2_emissions(2,ii) = mSolver.co2EmissionsNaturalGas;
                    rec.co2_emissions(3,ii) = mSolver.co2EmissionsRenewableNaturalGas;
                
                    % Total carbon emissions
                    rec.co2_emissions(4,ii) =   mSolver.co2TotalEmissions;
                
                    % Percent reduction
                    rec.co2_emissions_red(1,ii) = mSolver.co2EmissionsReductionPercentaje;
                
                    %% Financials
                    %%%($/kWh)
                    rec.financials.lcoe(ii,1) = mSolver.levelizedCostOfElectricityInKWh;
                
                    %%%Bulk cost of carbon ($/tonne)
                    rec.financials.cost_of_co2(ii,1) = mSolver.BulkCostOfCarbonByTonne;
                
                    %%%Marginal cost of carbon ($/tonne)
                    if ii > 1
                        rec.financials.cost_of_co2_marginal(ii,1) = abs((rec.solver.fval(ii,1) - rec.solver.fval(ii-1,1))/((rec.co2_emissions(4,ii) - rec.co2_emissions(4,ii-1))./1000));
                    else 
                        rec.financials.cost_of_co2_marginal(ii,1) = NaN;
                    end
                
                    %%%Capital Cost Requirements
                    rec.financials.cap_cost(ii,:) = [mSolver.solarPhotoVoltaicAdopt.*techSelOnSite.pv_cap*capCostMods.pv_cap_mod
                                                    mSolver.electricalEnergyStorage_adopt.*techSelOnSite.ees_cap.*capCostMods.ees_cap_mod
                                                    mSolver.renewableElectricalEnergyStorage_adopt.*techSelOnSite.ees_cap.*capCostMods.rees_cap_mod
                                                    mSolver.h2ProductionElectrolyzer_adopt.*techSelOnSite.el_cap.*capCostMods.el_cap_mod
                                                    mSolver.h2ProductionRenewableElectrolyzer_adopt.*techSelOnSite.el_cap.*capCostMods.rel_cap_mod
                                                    mSolver.h2ProductionEnergyStorage_adopt.*techSelOnSite.h2es_cap];

                    % Dispatch Plots
                    idx = ii;

                    co2_red_text = ['CO2 Reduction:' num2str((app.cfgManager.co2_red(idx)*100),'%2d') '%'];
                     
                    dt1 = [sum(rec.ldg.ldg_elec(:,idx),2)...
                            sum(rec.utility.import(:,idx),2)...
                            sum(rec.solar.pv_elec(:,idx),2) + sum(rec.rees.rees_chrg(:,idx),2)...
                            sum(rec.ees.ees_dchrg(:,idx),2) + sum(rec.rees.rees_dchrg(:,idx),2) + sum(rec.lees.ees_dchrg(:,idx),2)];
                    
                    dt2 = [bldgData.elec ...
                            sum(rec.ees.ees_chrg(:,idx),2) + sum(rec.lees.ees_chrg(:,idx),2) + sum(rec.rees.rees_chrg(:,idx),2)...
                            sum(modelVars.el_eff.*rec.el.el_prod(:,idx),2) + sum(modelVars.h2_chrg_eff.*rec.h2es.h2es_chrg(:,idx),2) + sum(modelVars.rel_eff.*rec.rel.rel_prod(:,idx),2) ...
                            rec.solar.pv_nem(:,idx)];


                    app.plotData_text = [app.plotData_text convertCharsToStrings(co2_red_text)];
                    app.plotData_dt1(:,:,ii) = dt1;
                    app.plotData_dt2(:,:,ii) = dt2;

                    app.UpdatePlots(ii);

                    %--------------------------------------------------
                    % Plot 'Electric Sources (MW)'
                    %--------------------------------------------------
        
                    % app.PlotElectricLoads.Title.String = co2_red_text;
                    % area(app.PlotElectricLoads, bldgData.time, dt1.*4./1000)
                    % set(app.PlotElectricLoads,'XTick', round(bldgData.time(1),0)+.5:round(bldgData.time(end),0)+.5,'FontSize',14)
                    % app.PlotElectricLoads.Box = "on";
                    % app.PlotElectricLoads.XGrid = "on";
                    % datetick(app.PlotElectricLoads,'x','ddd','keepticks');
                    % app.PlotElectricLoads.XLim = [bldgData.time(bldgData.stpts(3)) bldgData.time(bldgData.stpts(3)+96*7)];
                    % ylabel(app.PlotElectricLoads,'Electric Sources (MW)','FontSize',18)
                    % legend(app.PlotElectricLoads,'Gas Turbine','Utility Import','Solar','Battery Discharge','Location','Best')
                    % app.PlotElectricLoads.Visible = true;
            

                    %--------------------------------------------------
                    % Plot 'Electric Loads (MW)'
                    %--------------------------------------------------
        
                    % app.PlotElectricSources.Title.String = co2_red_text;
                    % area(app.PlotElectricSources, bldgData.time, dt2.*4./1000)
                    % set(app.PlotElectricSources,'XTick', round(bldgData.time(1),0)+.5:round(bldgData.time(end),0)+.5,'FontSize',14)
                    % app.PlotElectricSources.Box = "on";
                    % app.PlotElectricSources.XGrid = "on";
                    % datetick(app.PlotElectricSources,'x','ddd','keepticks');
                    % app.PlotElectricSources.XLim = [bldgData.time(bldgData.stpts(3)) bldgData.time(bldgData.stpts(3)+96*7)];
                    % ylabel(app.PlotElectricSources,'Electric Sources (MW)','FontSize',18)
                    % legend(app.PlotElectricSources,'Campus','Battery Charging','H_2 Production','Export','Location','Best')
                    % app.PlotElectricSources.Visible = true;

                    pause(1)

                end
                
                %--------------------------------------------------------------------------
                %        SHOW OTHER RESULTS
                %--------------------------------------------------------------------------
                plotLimits = [app.cfgManager.co2_red(1) app.cfgManager.co2_red(length(app.cfgManager.co2_red))] * 100;


                % Plot 'LCOE ($/kWh)'

                app.PlotLCOE.Title.String = "Levelized Cost of Electricity";
                hold(app.PlotLCOE,'on');
                plot(app.PlotLCOE, rec.co2_emissions_red, rec.financials.lcoe,'LineWidth',2);
                app.PlotLCOE.Box = "on";
                app.PlotLCOE.XGrid = "on";
                app.PlotLCOE.YGrid = "on";
                ylabel(app.PlotLCOE,'LCOE ($/kWh)','FontSize',18);
                xlabel(app.PlotLCOE,'CO_2 Reduction (%)','FontSize',18);
                app.PlotLCOE.XLim = plotLimits;
                app.PlotLCOE.Visible = true;
                

                % Plot 'Cost of Carbon'

                app.PlotCostOfCO2.Title.String = "Cost of Carbon";
                hold(app.PlotCostOfCO2,'on');
                plot(app.PlotCostOfCO2, rec.co2_emissions_red,rec.financials.cost_of_co2,'LineWidth',2)
                plot(app.PlotCostOfCO2, rec.co2_emissions_red,rec.financials.cost_of_co2_marginal,'LineWidth',2)
                app.PlotCostOfCO2.Box = "on";
                app.PlotCostOfCO2.XGrid = "on";
                app.PlotCostOfCO2.YGrid = "on";
                ylabel(app.PlotCostOfCO2,'Cost of CO_2 ($/tonne)','FontSize',18)
                xlabel(app.PlotCostOfCO2,'CO_2 Reduction (%)','FontSize',18)
                app.PlotCostOfCO2.XLim = plotLimits;
                legend(app.PlotCostOfCO2,'Average Cost','Marginal Cost','Location','NorthWest')
                app.PlotCostOfCO2.Visible = true;


                %%% Plot 'Capital Requirements'

                app.PlotCapitalCost.Title.String = "Capital Requirements";
                hold(app.PlotCapitalCost,'on');
                plot(app.PlotCapitalCost, rec.co2_emissions_red,sum(rec.financials.cap_cost,2)./1000000,'LineWidth',2)
                app.PlotCapitalCost.Box = "on";
                app.PlotCapitalCost.XGrid = "on";
                app.PlotCapitalCost.YGrid = "on";
                ylabel(app.PlotCapitalCost,'Capital Cost ($MM)','FontSize',18)
                xlabel(app.PlotCapitalCost,'CO_2 Reduction (%)','FontSize',18)
                app.PlotCapitalCost.XLim = plotLimits;
                app.PlotCapitalCost.Visible = true;


                % Update optimization results
                app.optimizationRecordings = rec;

                app.CO2ReductionSlider.Visible = true;
                app.CO2ReductionSliderLabel.Visible = true;

            else

                close(progBar);
                msgbox(append("Could not load Building Data File: ", append(app.cfgManager.demo_data_path, '\Campus_Loads_2014_2019.mat')),"0CF DERoptimizer","warn");

            end

        end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            opList = {'1 - Robert''s PC', '2 - Roman''s Laptop', '3 - Roman''s Desktop'};

            [indx,~] = listdlg('ListString',opList,'SelectionMode','single', 'InitialValue',1,'PromptString','Select Environment','ListSize',[160 60]);

            if isempty(indx)
                indx = 1;
            end

            SetCodePaths(indx);

            app.cfgManager = CConfigurationManager;

            % Select which computer your are running this app
            app.cfgManager.SetRunningEnvironment(indx); % 1 - Robert's PC
                                                     % 2 - Roman's Laptop
                                                     % 3 - Roman's Desktop

            app.cfgManager.AddMatlabPaths()


            % Define default values for all controls
            app.JanuaryCheckBox.Value = true;
            app.AprilCheckBox.Value = true;
            app.JulyCheckBox.Value = true;
            app.OctoberCheckBox.Value = true;

            app.FilesLocationDropDown.Value = num2str(indx);

            app.PlotElectricLoads.Visible = false;
            app.PlotElectricSources.Visible = false;
            app.PlotLCOE.Visible = false;
            app.PlotCostOfCO2.Visible = false;
            app.PlotCapitalCost.Visible = false;

            app.CO2ReductionSlider.Visible = false;
            app.CO2ReductionSliderLabel.Visible = false;

        end

        % Button pushed function: StartOptimizationButton
        function StartOptimizationButtonPushed(app, event)
            
            UpdateAllVariables(app)

            if isempty(app.cfgManager.month_idx)

                msgbox("No months are select to format the Building Data!","","warn")

            else

                % Validate all inputs
                app.ValidateInitialPathCO2Reduction();

                if app.NumberofoptimizationloopsEditField.Value <= 10

                    clc

                    app.CO2ReductionSlider.Value = app.cfgManager.co2_red(length(app.cfgManager.co2_red)) * 100;

                    app.Optimize();

                else
                    msgbox("Too many optimization loops. Please, review START, FINISH and STEP values","","warn")
                end

            end

        end

        % Value changed function: FinishValueEditField, 
        % ...and 2 other components
        function StartValueEditFieldValueChanged(app, event)
            
            app.ValidateInitialPathCO2Reduction();            
            
        end

        % Value changed function: CO2ReductionSlider
        function CO2ReductionSliderValueChanged(app, event)

            value = app.CO2ReductionSlider.Value;

            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);

            % Update Plots
            if ~isempty(app.optimizationRecordings)

                plotIndex = find(app.cfgManager.co2_red == value/100);

                app.UpdatePlots(plotIndex);

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DEROptimizerTab = uitab(app.TabGroup);
            app.DEROptimizerTab.Title = 'DER Optimizer';
            app.PlotElectricLoads = uiaxes(app.DEROptimizerTab);
            title(app.PlotElectricLoads, 'Title')
            ylabel(app.PlotElectricLoads, 'Y')
            zlabel(app.PlotElectricLoads, 'Z')
            app.PlotElectricSources = uiaxes(app.DEROptimizerTab);
            title(app.PlotElectricSources, 'Title')
            ylabel(app.PlotElectricSources, 'Y')
            zlabel(app.PlotElectricSources, 'Z')
            app.IntialCO2ReductionPathLabel = uilabel(app.DEROptimizerTab);
            app.IntialCO2ReductionPathLabel.Text = 'Intial CO2 Reduction Path:';
            app.StartValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.StartValueEditFieldLabel.Text = 'Start Value [%]';
            app.StartValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.StartValueEditField.Limits = [0 90];
            app.StartValueEditField.ValueDisplayFormat = '%.0f';
            app.StartValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.StartValueEditField.Tag = 'startValue';
            app.FinishValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.FinishValueEditFieldLabel.Text = 'Finish Value [%]';
            app.FinishValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.FinishValueEditField.Limits = [10 90];
            app.FinishValueEditField.ValueDisplayFormat = '%.0f';
            app.FinishValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.FinishValueEditField.Tag = 'startValue';
            app.FinishValueEditField.Value = 50;
            app.StepValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.StepValueEditFieldLabel.Text = 'Step Value [%]';
            app.StepValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.StepValueEditField.Limits = [1 99];
            app.StepValueEditField.ValueDisplayFormat = '%.0f';
            app.StepValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.StepValueEditField.Tag = 'startValue';
            app.StepValueEditField.Value = 25;
            app.SelectFacilityDropDownLabel = uilabel(app.DEROptimizerTab);
            app.SelectFacilityDropDownLabel.Text = 'Select Facility';
            app.SelectFacilityDropDown = uidropdown(app.DEROptimizerTab);
            app.SelectFacilityDropDown.Items = {'UC Irvine Labs', '0CF Test Facility 1', '0CF Test Facility 2'};
            app.SelectFacilityDropDown.ItemsData = {'1', '2', '3'};
            app.SelectFacilityDropDown.Value = '1';
            app.StartOptimizationButton = uibutton(app.DEROptimizerTab, 'push');
            app.StartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptimizationButtonPushed, true);
            app.StartOptimizationButton.Text = 'Start Optimization';
            app.NumberofoptimizationloopsEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.NumberofoptimizationloopsEditFieldLabel.Text = 'Number of optimization loops';
            app.NumberofoptimizationloopsEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.NumberofoptimizationloopsEditField.Editable = 'off';
            app.NumberofoptimizationloopsEditField.Value = 3;
            app.CO2ReductionSliderLabel = uilabel(app.DEROptimizerTab);
            app.CO2ReductionSliderLabel.Text = 'CO2 Reduction';
            app.CO2ReductionSlider = uislider(app.DEROptimizerTab);
            app.CO2ReductionSlider.ValueChangedFcn = createCallbackFcn(app, @CO2ReductionSliderValueChanged, true);
            app.CO2ReductionSlider.MinorTicks = [];
            app.OptimizationAnalysisTab = uitab(app.TabGroup);
            app.OptimizationAnalysisTab.Title = 'Optimization Analysis';
            app.PlotCapitalCost = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotCapitalCost, 'Title')
            ylabel(app.PlotCapitalCost, 'Y')
            zlabel(app.PlotCapitalCost, 'Z')
            app.PlotCostOfCO2 = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotCostOfCO2, 'Title')
            ylabel(app.PlotCostOfCO2, 'Y')
            zlabel(app.PlotCostOfCO2, 'Z')
            app.PlotLCOE = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotLCOE, 'Title')
            ylabel(app.PlotLCOE, 'Y')
            zlabel(app.PlotLCOE, 'Z')
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Selectwhichmonthsfrom2018toincludeinthecalculationLabel = uilabel(app.ConfigurationTab);
            app.Selectwhichmonthsfrom2018toincludeinthecalculationLabel.Text = 'Select which months from 2018 to include in the calculation:';
            app.JanuaryCheckBox = uicheckbox(app.ConfigurationTab);
            app.JanuaryCheckBox.Text = 'January';
            app.FebruaryCheckBox = uicheckbox(app.ConfigurationTab);
            app.FebruaryCheckBox.Text = 'February';
            app.MarchCheckBox = uicheckbox(app.ConfigurationTab);
            app.MarchCheckBox.Text = 'March';
            app.AprilCheckBox = uicheckbox(app.ConfigurationTab);
            app.AprilCheckBox.Text = 'April';
            app.MayCheckBox = uicheckbox(app.ConfigurationTab);
            app.MayCheckBox.Text = 'May';
            app.JuneCheckBox = uicheckbox(app.ConfigurationTab);
            app.JuneCheckBox.Text = 'June';
            app.JulyCheckBox = uicheckbox(app.ConfigurationTab);
            app.JulyCheckBox.Text = 'July';
            app.AugustCheckBox = uicheckbox(app.ConfigurationTab);
            app.AugustCheckBox.Text = 'August';
            app.SeptemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.SeptemberCheckBox.Text = 'September';
            app.OctoberCheckBox = uicheckbox(app.ConfigurationTab);
            app.OctoberCheckBox.Text = 'October';
            app.NovemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.NovemberCheckBox.Text = 'November';
            app.DecemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.DecemberCheckBox.Text = 'December';
            app.FilesLocationDropDownLabel = uilabel(app.ConfigurationTab);
            app.FilesLocationDropDownLabel.Text = 'Files Location';
            app.FilesLocationDropDown = uidropdown(app.ConfigurationTab);
            app.FilesLocationDropDown.Items = {'Robert''s PC', 'Roman''s Laptop', 'Roman''s Desktop'};
            app.FilesLocationDropDown.ItemsData = {'1', '2', '3'};
            app.FilesLocationDropDown.Value = '1';
            app.HydrogentechnologiesPanel = uipanel(app.ConfigurationTab);
            app.HydrogentechnologiesPanel.Title = 'Hydrogen technologies';
            app.H2ElectrolizerLabel = uilabel(app.HydrogentechnologiesPanel);
            app.H2ElectrolizerLabel.Text = 'H2 Electrolizer';
            app.SwElectrolizerToggle = uiswitch(app.HydrogentechnologiesPanel, 'slider');
            app.SwElectrolizerToggle.Value = 'On';
            app.RenewableH2ElectrolizerLabel = uilabel(app.HydrogentechnologiesPanel);
            app.RenewableH2ElectrolizerLabel.Text = 'Renewable H2 Electrolizer';
            app.SwRenewableElectrolizerToggle = uiswitch(app.HydrogentechnologiesPanel, 'slider');
            app.SwRenewableElectrolizerToggle.Value = 'On';
            app.ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel = uilabel(app.UIFigure);
            app.ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel.Text = 'ZERO CARBON FUTURE - Distributed Energy Resources Optimizer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroCF_DERopt_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end