classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputInfoTextArea            matlab.ui.control.TextArea
        OutputInfoTextAreaLabel       matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        ParameterConfigurationLabel   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        AppEnvironmentTab             matlab.ui.container.Tab
        FilesLocationDropDown         matlab.ui.control.DropDown
        FilesLocationDropDownLabel    matlab.ui.control.Label
        DataFilteringTab              matlab.ui.container.Tab
        DecemberCheckBox              matlab.ui.control.CheckBox
        NovemberCheckBox              matlab.ui.control.CheckBox
        OctoberCheckBox               matlab.ui.control.CheckBox
        SeptemberCheckBox             matlab.ui.control.CheckBox
        AugustCheckBox                matlab.ui.control.CheckBox
        JulyCheckBox                  matlab.ui.control.CheckBox
        JuneCheckBox                  matlab.ui.control.CheckBox
        MayCheckBox                   matlab.ui.control.CheckBox
        AprilCheckBox                 matlab.ui.control.CheckBox
        MarchCheckBox                 matlab.ui.control.CheckBox
        FebruaryCheckBox              matlab.ui.control.CheckBox
        JanuaryCheckBox               matlab.ui.control.CheckBox
        SelectwhichmonthstoincludeinthecalculationLabel  matlab.ui.control.Label
        TechnologiesConfigurationTab  matlab.ui.container.Tab
        StartOptimizationButton       matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        playground_run_cfg;
        demo_files_location = 1;        % 1 - Robert's PC
                                        % 2 - Roman's Laptop
                                        % 3 - Roman's Desktop
        optimizationRecordings;
    end

    methods (Access = private)
        
        function UpdateAllVariables(app)
            

            %--------------------------------------------------------------
            % Build cfg_month_idx array
            month_idx = [];

            if app.JanuaryCheckBox.Value == true
                month_idx = [month_idx 1];
            end
            if app.FebruaryCheckBox.Value == true
                month_idx = [month_idx 2];
            end
            if app.MarchCheckBox.Value == true
                month_idx = [month_idx 3];
            end
            if app.AprilCheckBox.Value == true
                month_idx = [month_idx 4];
            end            
            if app.MayCheckBox.Value == true
                month_idx = [month_idx 5];
            end
            if app.JuneCheckBox.Value == true
                month_idx = [month_idx 6];
            end
            if app.JulyCheckBox.Value == true
                month_idx = [month_idx 7];
            end
            if app.AugustCheckBox.Value == true
                month_idx = [month_idx 8];
            end
            if app.SeptemberCheckBox.Value == true
                month_idx = [month_idx 9];
            end
            if app.OctoberCheckBox.Value == true
                month_idx = [month_idx 10];
            end
            if app.NovemberCheckBox.Value == true
                month_idx = [month_idx 11];
            end
            if app.DecemberCheckBox.Value == true
                month_idx = [month_idx 12];
            end

            app.playground_run_cfg.month_idx = month_idx;

            app.demo_files_location = app.FilesLocationDropDown.Value;       

            % Demo files location
            if app.demo_files_location == '1'       % 1 - Robert's PC
            
                app.playground_run_cfg.files_path = 'H:\_Tools_';
                app.playground_run_cfg.data_path = 'H:\Data\UCI';
                app.playground_run_cfg.results_path = 'H:\_Tools_\UCI_Results\Sc19';
            
                app.playground_run_cfg.yalmip_master_path = 'H:\Matlab_Paths\YALMIP-master';
                app.playground_run_cfg.matlab_path = 'C:\Program Files\MATLAB\R2014b\YALMIP-master';
            
            
            elseif app.demo_files_location == '2'   % 2 - Roman's Laptop
            
                app.playground_run_cfg.files_path = 'C:\MotusVentures';
                app.playground_run_cfg.data_path = 'C:\MotusVentures\DERopt\Data';
                app.playground_run_cfg.results_path = 'C:\MotusVentures\DERopt\SolveResults';
            
                app.playground_run_cfg.yalmip_master_path = 'C:\MotusVentures\YALMIP-master';
                app.playground_run_cfg.matlab_path = 'C:\Program Files\MATLAB\R2023a\YALMIP-master';
            
            
            else                                % 3 - Roman's Desktop
            
                app.playground_run_cfg.files_path = 'E:\MotusVentures';
                app.playground_run_cfg.data_path = 'E:\MotusVentures\DERopt\Data';
                app.playground_run_cfg.results_path = 'E:\MotusVentures\DERopt\SolveResults';
            
                app.playground_run_cfg.yalmip_master_path = 'C:\MotusVentures\YALMIP-master';
                app.playground_run_cfg.matlab_path = 'C:\Program Files\MATLAB\R2023a\YALMIP-master';
            
            end

        end


        function PlotResults(app)

            optRec = app.optimizationRecordings;

            % % Plot 'LCOE ($/kWh)'
            % figure
            % hold on
            % plot(optRec.co2_emissions_red,optRec.financials.lcoe,'LineWidth',2)
            % box on
            % grid on
            % ylabel('LCOE ($/kWh)','FontSize',18)
            % xlabel('CO_2 Reduction (%)','FontSize',18)
            % set(gcf,'Position',[100 100 500 275])
            % xlim([0 50])
            % hold off
            % 
            % 
            % % Plot 'Cost of Carbon'
            % close all
            % figure
            % hold on
            % plot(optRec.co2_emissions_red,optRec.financials.cost_of_co2,'LineWidth',2)
            % plot(optRec.co2_emissions_red,optRec.financials.cost_of_co2_marginal,'LineWidth',2)
            % box on
            % grid on
            % ylabel('Cost of CO_2 ($/tonne)','FontSize',18)
            % xlabel('CO_2 Reduction (%)','FontSize',18)
            % set(gcf,'Position',[100 100 500 275])
            % xlim([5 50])
            % legend('Average Cost','Marginal Cost','Location','NorthWest')
            % hold off
            % 
            % 
            % % Plot 'Capital Requirements'
            % close all
            % figure
            % hold on
            % plot(optRec.co2_emissions_red,sum(optRec.financials.cap_cost,2)./1000000,'LineWidth',2)
            % %     plot(optRec.co2_emissions_red,optRec.financials.cost_of_co2_marginal,'LineWidth',2)
            % box on
            % grid on
            % ylabel('Capital Cost ($MM)','FontSize',18)
            % xlabel('CO_2 Reduction (%)','FontSize',18)
            %  set(gcf,'Position',[100 100 500 275])
            %  xlim([5 50])
            % %      legend('Average Cost','Marginal Cost','Location','NorthWest')
            % hold off
        
            %  close all

        
            % Dispatch Plots

            idx = 1;
            
            dt1 = [sum(optRec.ldg.ldg_elec(:,idx),2)...
                    sum(optRec.utility.import(:,idx),2)...
                    sum(optRec.solar.pv_elec(:,idx),2) + sum(optRec.rees.rees_chrg(:,idx),2)...
                    sum(optRec.ees.ees_dchrg(:,idx),2) + sum(optRec.rees.rees_dchrg(:,idx),2) + sum(optRec.lees.ees_dchrg(:,idx),2)];
                 
            dt2 = [optRec.elec ...
                    sum(optRec.ees.ees_chrg(:,idx),2) + sum(optRec.lees.ees_chrg(:,idx),2) + sum(optRec.rees.rees_chrg(:,idx),2)...
                    sum(optRec.el_eff.*optRec.el.el_prod(:,idx),2) + sum(optRec.h2_chrg_eff.*optRec.h2es.h2es_chrg(:,idx),2) + sum(optRec.rel_eff.*optRec.rel.rel_prod(:,idx),2) ...
                    optRec.solar.pv_nem(:,idx)];
            

            %--------------------------------------------------
            % Plot 'Electric Sources (MW)'
            %--------------------------------------------------

            area(app.UIAxes, optRec.time, dt1.*4./1000)
            set(app.UIAxes,'XTick', round(optRec.time(1),0)+.5:round(optRec.time(end),0)+.5,'FontSize',14)
            app.UIAxes.Box = "on";
            app.UIAxes.XGrid = "on";
            datetick(app.UIAxes,'x','ddd','keepticks');
            app.UIAxes.XLim = [optRec.time(optRec.stpts(3)) optRec.time(optRec.stpts(3)+96*7)];
            ylabel(app.UIAxes,'Electric Sources (MW)','FontSize',18)
            legend(app.UIAxes,'Gas Turbine','Utility Import','Solar','Battery Discharge','Location','Best')
            app.UIAxes.Visible = "on";

            %--------------------------------------------------
            % Plot 'Electric Loads (MW)'
            %--------------------------------------------------

            area(app.UIAxes2, optRec.time, dt2.*4./1000)
            set(app.UIAxes2,'XTick', round(optRec.time(1),0)+.5:round(optRec.time(end),0)+.5,'FontSize',14)
            app.UIAxes2.Box = "on";
            app.UIAxes2.XGrid = "on";
            datetick(app.UIAxes2,'x','ddd','keepticks');
            app.UIAxes2.XLim = [optRec.time(optRec.stpts(3)) optRec.time(optRec.stpts(3)+96*7)];
            ylabel(app.UIAxes2,'Electric Sources (MW)','FontSize',18)
            legend(app.UIAxes2,'Campus','Battery Charging','H_2 Production','Export','Location','Best')
            app.UIAxes2.Visible = "on";

        end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Create all variables and default values
            
            % Baseline CO2 emissions [kg]
            app.playground_run_cfg.co2_baseline_emissions_kg = [];
            
            % Desired reduction
            %playground_run_cfg.co2_desired_amount_reduction = [0:0.05:.5];
            app.playground_run_cfg.co2_desired_amount_reduction = [0 0.05];
            
            % Building DataValues to filter data by
            app.playground_run_cfg.month_idx = [1 4 7 10];
            app.playground_run_cfg.year_idx = 2018;
            
            % Define default values for all controls
            app.JanuaryCheckBox.Value = true;
            app.AprilCheckBox.Value = true;
            app.JulyCheckBox.Value = true;
            app.OctoberCheckBox.Value = true;

            app.FilesLocationDropDown.Value = '1';

            % update after setting default values
            UpdateAllVariables(app)

        end

        % Button pushed function: StartOptimizationButton
        function StartOptimizationButtonPushed(app, event)
            
            UpdateAllVariables(app)


            %app.OutputInfoTextArea.Value = strjoin(string(app.MonthIDX));

            if isempty(app.playground_run_cfg.month_idx)

                % show message???

            else

                app.optimizationRecordings = playground_demo_function(app.playground_run_cfg);

            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            app.demo_files_location = app.FilesLocationDropDown.Value; 
            
            % Demo files location
            if app.demo_files_location == '1'       % 1 - Robert's PC
                app.playground_run_cfg.results_path = 'H:\_Tools_\UCI_Results\Sc19';
            elseif app.demo_files_location == '2'   % 2 - Roman's Laptop
                app.playground_run_cfg.results_path = 'C:\MotusVentures\DERopt\SolveResults';
            else                                % 3 - Roman's Desktop
                app.playground_run_cfg.results_path = 'E:\MotusVentures\DERopt\SolveResults';
            end

            app.optimizationRecordings = LoadPlaygroundResults(append(app.playground_run_cfg.results_path,'\deropt_results.mat'))

            app.PlotResults();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.StartOptimizationButton = uibutton(app.UIFigure, 'push');
            app.StartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptimizationButtonPushed, true);
            app.StartOptimizationButton.Text = 'Start Optimization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AppEnvironmentTab = uitab(app.TabGroup);
            app.AppEnvironmentTab.Title = 'App Environment';
            app.FilesLocationDropDownLabel = uilabel(app.AppEnvironmentTab);
            app.FilesLocationDropDownLabel.Text = 'Files Location';
            app.FilesLocationDropDown = uidropdown(app.AppEnvironmentTab);
            app.FilesLocationDropDown.Items = {'Robert''s PC', 'Roman''s Laptop', 'Roman''s Desktop'};
            app.FilesLocationDropDown.ItemsData = {'1', '2', '3'};
            app.FilesLocationDropDown.Value = '1';
            app.DataFilteringTab = uitab(app.TabGroup);
            app.DataFilteringTab.Title = 'Data Filtering';
            app.SelectwhichmonthstoincludeinthecalculationLabel = uilabel(app.DataFilteringTab);
            app.SelectwhichmonthstoincludeinthecalculationLabel.Text = 'Select which months to include in the calculation:';
            app.JanuaryCheckBox = uicheckbox(app.DataFilteringTab);
            app.JanuaryCheckBox.Text = 'January';
            app.FebruaryCheckBox = uicheckbox(app.DataFilteringTab);
            app.FebruaryCheckBox.Text = 'February';
            app.MarchCheckBox = uicheckbox(app.DataFilteringTab);
            app.MarchCheckBox.Text = 'March';
            app.AprilCheckBox = uicheckbox(app.DataFilteringTab);
            app.AprilCheckBox.Text = 'April';
            app.MayCheckBox = uicheckbox(app.DataFilteringTab);
            app.MayCheckBox.Text = 'May';
            app.JuneCheckBox = uicheckbox(app.DataFilteringTab);
            app.JuneCheckBox.Text = 'June';
            app.JulyCheckBox = uicheckbox(app.DataFilteringTab);
            app.JulyCheckBox.Text = 'July';
            app.AugustCheckBox = uicheckbox(app.DataFilteringTab);
            app.AugustCheckBox.Text = 'August';
            app.SeptemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.SeptemberCheckBox.Text = 'September';
            app.OctoberCheckBox = uicheckbox(app.DataFilteringTab);
            app.OctoberCheckBox.Text = 'October';
            app.NovemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.NovemberCheckBox.Text = 'November';
            app.DecemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.DecemberCheckBox.Text = 'December';
            app.TechnologiesConfigurationTab = uitab(app.TabGroup);
            app.TechnologiesConfigurationTab.Title = 'Technologies Configuration';
            app.ParameterConfigurationLabel = uilabel(app.UIFigure);
            app.ParameterConfigurationLabel.Text = 'Parameter Configuration';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.OutputInfoTextAreaLabel = uilabel(app.UIFigure);
            app.OutputInfoTextAreaLabel.Text = 'Output Info';
            app.OutputInfoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end